classdef Crycom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CryComUIFigure                  matlab.ui.Figure
        GenT100ControlModuleRS232Panel  matlab.ui.container.Panel
        GridLayout_3                    matlab.ui.container.GridLayout
        ModelSwitch_2                   matlab.ui.control.Switch
        ModelSwitch_2Label              matlab.ui.control.Label
        COMPortNumberEditField232       matlab.ui.control.NumericEditField
        COMPortNumberEditField_2Label   matlab.ui.control.Label
        RestartButton_2                 matlab.ui.control.Button
        ErrorLamp232                    matlab.ui.control.Lamp
        ErrorLamp_2Label                matlab.ui.control.Label
        AckButton232                    matlab.ui.control.Button
        MainPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxesT2                        matlab.ui.control.UIAxes
        UIAxesT1                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ErstVakSensorRS485Panel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        SensorAddressDropDown           matlab.ui.control.DropDown
        SensorAddressDropDownLabel      matlab.ui.control.Label
        AckButton                       matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        ModelSwitchLabel                matlab.ui.control.Label
        ModelSwitch                     matlab.ui.control.Switch
        COMPortNumberEditField          matlab.ui.control.NumericEditField
        COMPortNumberEditFieldLabel     matlab.ui.control.Label
        ErrorLamp                       matlab.ui.control.Lamp
        ErrorLampLabel                  matlab.ui.control.Label
        MeasurmentMonitorPanel          matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        ExperimentNumberEditField       matlab.ui.control.NumericEditField
        ExperimentNumberEditFieldLabel  matlab.ui.control.Label
        ExperimentDescriptionEditField  matlab.ui.control.EditField
        ExperimentDescriptionEditFieldLabel  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        SampleTimeDropDownLabel         matlab.ui.control.Label
        SampleTimeDropDown              matlab.ui.control.DropDown
        PressurembarEditFieldLabel_2    matlab.ui.control.Label
        TempCh2                         matlab.ui.control.NumericEditField
        TemperatureCH2KLabel            matlab.ui.control.Label
        TempCh1                         matlab.ui.control.NumericEditField
        TemperatureCH1KLabel            matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        StopButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        Pressure                        matlab.ui.control.NumericEditField
        PressurembarEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        %ErsteVak protocol commands
        typeCommand = "T";
        measurmentValueCommand = "M";
        setpointReadCommand = "S";
        setpointWriteCommand = "s";
        correctionCoefReadCommand = "C"'
        correctionCoefWriteCommand = "c";
        coldModeReadCommand = "I";
        coldModeWriteCommand = "i";
        modeSynchronizationReadCommand = "W";
        modeSynchronizationWriteCommand = "w";
        pressureRegulationCommand = "j";
        
        %GenT protocol Commands
        tempCh1 = "T1";
        tempCh2 = "T2";
        tempChAll = "TA";
        enqCommand = char(5); %<ENQ>


        %port 485 and connection confuguration FOR VAC
        sensorAddress;
        port;
        
        %port 232 FOR TEMP
        port232;


        %app fields
        stopFlag;
        measurmentPeriod;

    end
    
    methods (Access = private)
        
        % ACD = address + command + data message
        % ACDC = address + command + data message + cks (control sum)
        
        function ACDMsg = ACDMsgBuilder(app, command, data)
            ACDMsg = char(strcat(app.sensorAddress, command, data));          
        end
        
        
        function cks = cksCalc(~, ACDMsg)
            ACDMsgSum = sum(double(ACDMsg));
            cks = char(mod(ACDMsgSum, 64) + 64);
        end
        
        
        function chekedACDMsg = checkedACDMsgBuilder(~, ACDMsg, cks)
            chekedACDMsg = strcat(ACDMsg, cks); 
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.CryComUIFigure.Icon = 'Icons/icon.ico';


            app.measurmentPeriod = 1;
            

            %rise RS485 port
            try 
                portNumber = strcat('COM', num2str(app.COMPortNumberEditField.Value));
                app.port = serialport(portNumber, 9600, "Parity", "none", "Timeout", 10, "DataBits", 8, "ByteOrder", "little-endian", "StopBits", 1);
                configureTerminator(app.port, 'CR'); 
            catch ME
                disp(ME);
                disp("RS485 Problem");
                app.ErrorLamp.Color = [1 0 0];
            end
            
            %rise RS232 port
            try
                port232Number = strcat('COM', num2str(app.COMPortNumberEditField232.Value));
                app.port232 = serialport(port232Number, 9600, "Parity", "none", "Timeout", 10, "DataBits", 8, "ByteOrder", "little-endian", "StopBits", 1);
                configureTerminator(app.port232, 'CR');
            catch ME
                disp(ME);
                disp("RS232 Problem");
                app.ErrorLamp232.Color = [1 0 0];
            end


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ExperimentDescriptionEditField.Editable = false;
            app.sensorAddress = app.SensorAddressDropDown.Value;
            app.stopFlag = false;
            %reportFile
            dateTimeMarker = datestr(datetime, 'yyyy-mm-dd_HH-MM-SS');
            mkdir('reportLogs');
            reportFileName = strcat('reportLogs/', dateTimeMarker, '_report_', app.ExperimentDescriptionEditField.Value, '.csv'); 
            description = {app.ExperimentDescriptionEditField.Value};
            writecell(description, reportFileName,'WriteMode','append');
            %disp(app.measurmentPeriod)
            %class(app.measurmentPeriod)
            rateObject = rateControl(1/app.measurmentPeriod);
            
            
            try
                %build msg for pressure request
                command = app.measurmentValueCommand;
                data='';
                ACDMsg = ACDMsgBuilder(app, command, data);
                cks = cksCalc(app, ACDMsg);
                checkedACDMsg = checkedACDMsgBuilder(app, ACDMsg, cks);
                
                %build msg for temp request
                commandTA = 'TA';
                                
                
            catch ME
                disp('Msg construction ERROR')
            end


            %falling factor step for model
            modelStep = 0;
            
            %build plot axes
            plot(app.UIAxes, datetime('now'), 1000, 'MarkerEdgeColor', 'none');
            plot(app.UIAxesT1, datetime('now'), 1000, 'MarkerEdgeColor', 'none');
            plot(app.UIAxesT2, datetime('now'), 1000, 'MarkerEdgeColor', 'none');
           
            % Set x limits and ticks
            t = datetime('now') + seconds(1:120);
            %xLL = t(1) - seconds(5);
            xLL = t(1);
            xUL = t(length(t));
            app.UIAxes.XLim = [xLL xUL];
            app.UIAxes.XAxis.TickLabelFormat = 'HH:mm:ss';
            app.UIAxesT1.XLim = [xLL xUL];
            app.UIAxesT1.XAxis.TickLabelFormat = 'HH:mm:ss';
            app.UIAxesT2.XLim = [xLL xUL];
            app.UIAxesT2.XAxis.TickLabelFormat = 'HH:mm:ss';


            %app.UIAxes.XTick = linspace(xLL, xUL, 20);
            
            % Set y limits and ticks
            app.UIAxes.YLim = [100 1100];
            app.UIAxesT1.YLim = [-10 300];
            app.UIAxesT2.YLim = [-10 300];
            
            %app.UIAxes.YTick = round(linspace(0, 1000, 11), 2, 'significant');
            grid(app.UIAxes,'on');
            grid(app.UIAxesT1,'on');
            grid(app.UIAxesT2,'on');
            hold(app.UIAxes,'on');
            hold(app.UIAxesT1,'on');
            hold(app.UIAxesT2,'on');


            modelMode = str2double(app.ModelSwitch.Value);
            
            reset(rateObject);
            plotNumber = 1;
            firstTime = 0;
            while   app.stopFlag == false

                if modelMode == 0
                    %request pressure
                    write(app.port, checkedACDMsg, "string");
                    write(app.port, char(13), "string");
                    
                    %request TA;
                    write(app.port232, char(5), "string");
                    write(app.port232, commandTA, "string");
                    write(app.port232, char(13), "string");
                    
                    pause(0.3);
                    
                    frameSize = app.port.NumBytesAvailable;
                    
                    rawData = read(app.port, frameSize, "char");
                    mantissa = str2double(rawData(5:8)) / 1000;
                    order = str2double(rawData(9:10)) - 20;
                    pressure = mantissa * 10 ^ order;
                    
                    frameSize232 = app.port232.NumBytesAvailable;
                    rawData232 = read(app.port232, frameSize232, "string");
                    T1string = string(extractBetween(rawData232,char(6),'K'));
                    T2string = string(extractBetween(rawData232,' ','K'));
                    T1 = str2double(T1string);
                    T2 = str2double(T2string);
                    
                    flush(app.port);
                    flush(app.port232);
                    
                    
                else
                    modelPressure = 1000*exp(-0.07681*modelStep);
                    
                    pressure = modelPressure;
                end
                modelStep = modelStep + 1;
                
                
                %pressure falling model

                
                app.Pressure.Value = pressure;
                app.TempCh1.Value = T1;
                app.TempCh2.Value = T2;


                app.TimeEditField.Value = string(datetime);


                %plotting
                tnow = datetime('now');
                if plotNumber == 1
                    firstTime = tnow;
                    columnNames = {'relativeTime', 'tnow', 'pressure', 'T1', 'T2'};
                    writecell(columnNames, reportFileName,'WriteMode','append');
                end
                relativeTime = seconds(tnow - firstTime);
                
                exportData = {relativeTime, tnow, pressure, T1, T2};
                writecell(exportData, reportFileName,'WriteMode','append');


                xLL = app.UIAxes.XLim(1);
                xUL = app.UIAxes.XLim(2);
                
                if(tnow > xUL)
                   
                   xtra = seconds(120);
                   app.UIAxes.XLim = [xLL+xtra tnow+xtra];
                   app.UIAxesT1.XLim = [xLL+xtra tnow+xtra];
                   app.UIAxesT2.XLim = [xLL+xtra tnow+xtra];
                   delete(app.UIAxes.Children(1:length(app.UIAxes.Children)-1));
                   delete(app.UIAxesT1.Children(1:length(app.UIAxesT1.Children)-1));
                   delete(app.UIAxesT2.Children(1:length(app.UIAxesT2.Children)-1));
                   
                   
                   %app.UIAxes.XTick = linspace(xLL+xtra, tnow+xtra, 1);
                end


                %pressure plot
                p = plot(app.UIAxes, tnow, pressure, 'Marker', 'o','MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.5 0.5 0.5]);
                yLL = app.UIAxes.YLim(1);
                yUL = app.UIAxes.YLim(2);
                
                
                if(pressure > yUL)
                   xtra = pressure*0.5;
                   %disp('hh')
                   app.UIAxes.YLim = [yLL+xtra pressure+xtra];
                   %app.UIAxes.YTick = linspace(yLL+xtra, tnow+xtra, 1);
                end
                if(pressure < yLL)
                   xtra = pressure*0.5;
                   %disp('lo');
                   app.UIAxes.YLim = [pressure-xtra yUL-xtra];
                   %app.UIAxes.XTick = linspace(yLL+xtra, tnow+xtra, 1);
                end
                
                %T1 plot
                p = plot(app.UIAxesT1, tnow, T1, 'Marker', 'o','MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.5 0.5 0.5]);
                yLL = app.UIAxesT1.YLim(1);
                yUL = app.UIAxesT1.YLim(2);
                
                
                if(T1 > yUL)
                   xtra = T1*0.5;
                   %disp('hh')
                   app.UIAxesT1.YLim = [yLL+xtra T1+xtra];
                   %app.UIAxes.YTick = linspace(yLL+xtra, tnow+xtra, 1);
                end
                if(T1 < yLL)
                   xtra = T1*0.5;
                   %disp('lo');
                   app.UIAxesT1.YLim = [T1-xtra yUL-xtra];
                   %app.UIAxes.XTick = linspace(yLL+xtra, tnow+xtra, 1);
                end
                
                %T2 plot
                p = plot(app.UIAxesT2, tnow, T2, 'Marker', 'o','MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.5 0.5 0.5]);
                yLL = app.UIAxesT2.YLim(1);
                yUL = app.UIAxesT2.YLim(2);
                
                
                if(T2> yUL)
                   xtra = T2*0.5;
                   %disp('hh')
                   app.UIAxesT2.YLim = [yLL+xtra T2+xtra];
                   %app.UIAxes.YTick = linspace(yLL+xtra, tnow+xtra, 1);
                end
                if(T2 < yLL)
                   xtra = T2*0.5;
                   %disp('lo');
                   app.UIAxesT2.YLim = [T2-xtra yUL-xtra];
                   %app.UIAxes.XTick = linspace(yLL+xtra, tnow+xtra, 1);
                end
                plotNumber = plotNumber+1;

                
                grid(app.UIAxes,'on');
                hold(app.UIAxes,'on');


                waitfor(rateObject);
                
                
            end
            app.ExperimentDescriptionEditField.Editable = true;
            app.ExperimentDescriptionEditField.Value = 'Empty';
            %write(app.port, checkedACDMsg)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            try 
                app.port = 0;
                portNumber = strcat('COM', num2str(app.COMPortNumberEditField.Value));
                app.port = serialport(portNumber, 9600, "Parity", "none", "Timeout", 10, "DataBits", 8, "ByteOrder", "little-endian", "StopBits", 1);
                configureTerminator(app.port, 'CR'); 
            catch ME
                disp(ME);
                disp("RS485 Problem");
                app.ErrorLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: AckButton
        function AckButtonPushed(app, event)
            app.ErrorLamp.Color = [0.8 0.8 0.8];
        end

        % Callback function
        function ApplyButton_2Pushed(app, event)
            app.measurmentPeriod = str2double(app.SampleTimeDropDown.Value);
        end

        % Button pushed function: AckButton232
        function AckButton232Pushed(app, event)
            app.ErrorLamp232.Color = [0.8 0.8 0.8];
        end

        % Button pushed function: RestartButton_2
        function RestartButton_2Pushed(app, event)
            %rise RS232 port
            try
                app.port232 = 0;
                port232Number = strcat('COM', num2str(app.COMPortNumberEditField232.Value));
                app.port232 = serialport(port232Number, 9600, "Parity", "none", "Timeout", 10, "DataBits", 8, "ByteOrder", "little-endian", "StopBits", 1);
                configureTerminator(app.port232, 'CR');
            catch ME
                disp(ME);
                disp("RS232 Problem");
                app.ErrorLamp232.Color = [1 0 0];
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
             app.measurmentPeriod = str2double(app.SampleTimeDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CryComUIFigure = uifigure('Visible', 'off');
            app.CryComUIFigure.Name = 'CryCom';
            app.CryComUIFigure.Icon = 'CRYCOM.png';
            app.MeasurmentMonitorPanel = uipanel(app.CryComUIFigure);
            app.MeasurmentMonitorPanel.Title = 'Measurment Monitor';
            app.GridLayout_2 = uigridlayout(app.MeasurmentMonitorPanel);
            app.PressurembarEditFieldLabel = uilabel(app.GridLayout_2);
            app.PressurembarEditFieldLabel.Text = 'Pressure, mbar';
            app.Pressure = uieditfield(app.GridLayout_2, 'numeric');
            app.RunButton = uibutton(app.GridLayout_2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.GridLayout_2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TimeEditField = uieditfield(app.GridLayout_2, 'text');
            app.TemperatureCH1KLabel = uilabel(app.GridLayout_2);
            app.TemperatureCH1KLabel.Text = 'Temperature CH1, K';
            app.TempCh1 = uieditfield(app.GridLayout_2, 'numeric');
            app.TemperatureCH2KLabel = uilabel(app.GridLayout_2);
            app.TemperatureCH2KLabel.Text = 'Temperature CH2, K';
            app.TempCh2 = uieditfield(app.GridLayout_2, 'numeric');
            app.PressurembarEditFieldLabel_2 = uilabel(app.GridLayout_2);
            app.PressurembarEditFieldLabel_2.Text = 'Time';
            app.SampleTimeDropDown = uidropdown(app.GridLayout_2);
            app.SampleTimeDropDown.Items = {'1', '5', '10', '60'};
            app.SampleTimeDropDown.Value = '1';
            app.SampleTimeDropDownLabel = uilabel(app.GridLayout_2);
            app.SampleTimeDropDownLabel.Text = 'Sample Time';
            app.ApplyButton = uibutton(app.GridLayout_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Tooltip = {'Coming Soon'};
            app.ApplyButton.Text = 'Apply';
            app.ExperimentDescriptionEditFieldLabel = uilabel(app.GridLayout_2);
            app.ExperimentDescriptionEditFieldLabel.Text = 'Experiment Description';
            app.ExperimentDescriptionEditField = uieditfield(app.GridLayout_2, 'text');
            app.ExperimentDescriptionEditField.Value = 'Empty';
            app.ExperimentNumberEditFieldLabel = uilabel(app.GridLayout_2);
            app.ExperimentNumberEditFieldLabel.Visible = 'off';
            app.ExperimentNumberEditFieldLabel.Text = 'Experiment Number';
            app.ExperimentNumberEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.ExperimentNumberEditField.Editable = 'off';
            app.ExperimentNumberEditField.Visible = 'off';
            app.ErstVakSensorRS485Panel = uipanel(app.CryComUIFigure);
            app.ErstVakSensorRS485Panel.Title = 'ErstVak Sensor (RS485)';
            app.GridLayout = uigridlayout(app.ErstVakSensorRS485Panel);
            app.ErrorLampLabel = uilabel(app.GridLayout);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.GridLayout);
            app.ErrorLamp.Tooltip = {'Coming Soon'};
            app.COMPortNumberEditFieldLabel = uilabel(app.GridLayout);
            app.COMPortNumberEditFieldLabel.Text = 'COM Port Number';
            app.COMPortNumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.COMPortNumberEditField.Value = 1;
            app.ModelSwitch = uiswitch(app.GridLayout, 'slider');
            app.ModelSwitch.ItemsData = {'0', '1'};
            app.ModelSwitch.Enable = 'off';
            app.ModelSwitch.Value = '0';
            app.ModelSwitchLabel = uilabel(app.GridLayout);
            app.ModelSwitchLabel.Enable = 'off';
            app.ModelSwitchLabel.Text = 'Model';
            app.RestartButton = uibutton(app.GridLayout, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.AckButton = uibutton(app.GridLayout, 'push');
            app.AckButton.ButtonPushedFcn = createCallbackFcn(app, @AckButtonPushed, true);
            app.AckButton.Tooltip = {'Coming Soon'};
            app.AckButton.Text = 'Ack.';
            app.SensorAddressDropDownLabel = uilabel(app.GridLayout);
            app.SensorAddressDropDownLabel.Text = 'Sensor Address';
            app.SensorAddressDropDown = uidropdown(app.GridLayout);
            app.SensorAddressDropDown.Items = {'001', '002', '003', '004', '005', '006', '007', '008', '009', '010', '011', '012', '013', '014', '015', '016'};
            app.SensorAddressDropDown.Value = '001';
            app.MainPanel = uipanel(app.CryComUIFigure);
            app.MainPanel.Title = 'Main Panel';
            app.GridLayout2 = uigridlayout(app.MainPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Pressure in vacuum vessel')
            xlabel(app.UIAxes, 'Time, s')
            ylabel(app.UIAxes, 'Pressure, mbar')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.YScale = 'log';
            app.UIAxesT1 = uiaxes(app.GridLayout2);
            title(app.UIAxesT1, 'Temperature at channel 1')
            xlabel(app.UIAxesT1, 'Time, s')
            ylabel(app.UIAxesT1, 'Temperature, K')
            zlabel(app.UIAxesT1, 'Z')
            app.UIAxesT2 = uiaxes(app.GridLayout2);
            title(app.UIAxesT2, 'Temperature at channel 2')
            xlabel(app.UIAxesT2, 'Time, s')
            ylabel(app.UIAxesT2, 'Temperature, K')
            zlabel(app.UIAxesT2, 'Z')
            app.GenT100ControlModuleRS232Panel = uipanel(app.CryComUIFigure);
            app.GenT100ControlModuleRS232Panel.Title = 'GenT 100 Control Module (RS 232)';
            app.GridLayout_3 = uigridlayout(app.GenT100ControlModuleRS232Panel);
            app.AckButton232 = uibutton(app.GridLayout_3, 'push');
            app.AckButton232.ButtonPushedFcn = createCallbackFcn(app, @AckButton232Pushed, true);
            app.AckButton232.Tooltip = {'Coming Soon'};
            app.AckButton232.Text = 'Ack.';
            app.ErrorLamp_2Label = uilabel(app.GridLayout_3);
            app.ErrorLamp_2Label.Text = 'Error';
            app.ErrorLamp232 = uilamp(app.GridLayout_3);
            app.ErrorLamp232.Tooltip = {'Coming Soon'};
            app.RestartButton_2 = uibutton(app.GridLayout_3, 'push');
            app.RestartButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_2Pushed, true);
            app.RestartButton_2.Text = 'Restart';
            app.COMPortNumberEditField_2Label = uilabel(app.GridLayout_3);
            app.COMPortNumberEditField_2Label.Text = 'COM Port Number';
            app.COMPortNumberEditField232 = uieditfield(app.GridLayout_3, 'numeric');
            app.COMPortNumberEditField232.Value = 2;
            app.ModelSwitch_2Label = uilabel(app.GridLayout_3);
            app.ModelSwitch_2Label.Enable = 'off';
            app.ModelSwitch_2Label.Text = 'Model';
            app.ModelSwitch_2 = uiswitch(app.GridLayout_3, 'slider');
            app.ModelSwitch_2.ItemsData = {'0', '1'};
            app.ModelSwitch_2.Enable = 'off';
            app.ModelSwitch_2.Value = '0';
            % Show the figure after all components are created
            app.CryComUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crycom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CryComUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CryComUIFigure)
        end
    end
end